classdef EE416TermProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField       matlab.ui.control.NumericEditField
        DurationEditFieldLabel   matlab.ui.control.Label
        DurationEditField        matlab.ui.control.NumericEditField
        NumberEditFieldLabel     matlab.ui.control.Label
        NumberEditField          matlab.ui.control.NumericEditField
        DelayEditFieldLabel      matlab.ui.control.Label
        DelayEditField           matlab.ui.control.NumericEditField
        ActionButton             matlab.ui.control.Button
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes3_2                matlab.ui.control.UIAxes
        UIAxes3_3                matlab.ui.control.UIAxes
        UIAxes3_4                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
        end

        % Value changed function: DurationEditField
        function DurationEditFieldValueChanged(app, event)
            value = app.DurationEditField.Value;
        end

        % Value changed function: NumberEditField
        function NumberEditFieldValueChanged(app, event)
            value = app.NumberEditField.Value;
        end

        % Value changed function: DelayEditField
        function DelayEditFieldValueChanged(app, event)
            value = app.DelayEditField.Value;
        end

        % Button pushed function: ActionButton
        function ActionButtonPushed(app, event)
%Muhammed Saadeddin Kocak 2232346
%EE416 Term Project
t=linspace(-99,30000,30100);%usec
impulseduration=app.DurationEditField.Value;%usec
impulseamplitude=app.AmplitudeEditField.Value;%uA/cm^2
numberofconsecutivestimuli=app.NumberEditField.Value;
timedelaybetweenstimuli=app.DelayEditField.Value;%usec
Im(1:100)=0;%uA/sec^2
Cm=1;%uF/cm^2
Vm(1:length(t))=-90;%mV
vm(1:length(t))=0;%Vmembrane-Vrest
gNarest=120;%mS/cm^2
gKrest=36;%mS/cm^2
gLrest=0.3;%mS/cm^2
gK(1:length(t))=gKrest;
gNa(1:length(t))=gNarest;
gL(1:length(t))=gLrest;

VNa=25;%mV
VK=-102;%mV
VL=-79.387;%mV
for i=100:length(t)
   if t(i)>-1 && t(i)<impulseduration
       Im(i)=impulseamplitude;%uA/cm^2
   else
       Im(i)=0;
   end
   %For the second stimuli
   if numberofconsecutivestimuli~=1
   if t(i)>timedelaybetweenstimuli && t(i)<timedelaybetweenstimuli+impulseduration
       Im(i)=impulseamplitude;%uA/cm^2
   end
   end
   %
   an(i)=((0.01*(10-vm(i)))/(exp((10-vm(i))/10)-1))/1000;%1/usec
   bn(i)=(0.125*exp((-vm(i))/80))/1000;%1/usec
   am(i)=((0.1*(25-vm(i)))/(exp(0.1*(25-vm(i)))-1))/1000;%1/usec
   bm(i)=(4*exp((-vm(i))/18))/1000;%1/usec
   ah(i)=(0.07*exp((-vm(i))/20))/1000;%1/usec
   bh(i)=(1/(exp((30-vm(i))/10)+1))/1000;%1/usec
   n(100)=an(100)/(an(100)+bn(100));
   m(100)=am(100)/(am(100)+bm(100));
   h(100)=ah(100)/(ah(100)+bh(100));
   gK(i)=gKrest*(n(i).^4);%mS/cm^2
   gNa(i)=gNarest*(m(i).^3)*h(i);%mS/cm^2
   IK(i)=gK(i)*(Vm(i)-VK);%uA/cm^2
   INa(i)=gNa(i)*(Vm(i)-VNa);%uA/cm^2
   IL(i)=gLrest*(Vm(i)-VL);%uA/cm^2
   Ic(i)=Im(i)-IK(i)-INa(i)-IL(i);
   %Estimating deltan deltam deltah
   deltan(i)=(an(i)*(1-n(i)))-(bn(i)*n(i));
   deltam(i)=(am(i)*(1-m(i)))-(bm(i)*m(i));
   deltah(i)=(ah(i)*(1-h(i)))-(bh(i)*h(i));
   deltaVm(i)=(Im(i)-IK(i)-INa(i)-IL(i))/(Cm*1000);%mV
   %Calculating next variables
   n(i+1)=n(i)+deltan(i);
   m(i+1)=m(i)+deltam(i);
   h(i+1)=h(i)+deltah(i);
   Vm(i+1)=Vm(i)+deltaVm(i);
   vm(i+1)=vm(i)+deltaVm(i);
end
cla(app.UIAxes3);
cla(app.UIAxes3_2);
cla(app.UIAxes3_3);
cla(app.UIAxes3_4);
plot(app.UIAxes3,t,Im(1:length(t)));%Both for applied and membrane current
title(app.UIAxes3,'Applied Input Current (Total Mebrane Current) vs Time');
xlabel(app.UIAxes3,'Time (usec)');
ylabel(app.UIAxes3,'Current(uA/cm^2)');
hold(app.UIAxes3_2,'on');
plot(app.UIAxes3_2,t,IK(1:length(t)));
plot(app.UIAxes3_2,t,INa(1:length(t)));
plot(app.UIAxes3_2,t,IL(1:length(t)));
plot(app.UIAxes3_2,t,Ic(1:length(t)));
legend(app.UIAxes3_2,{'IK','INa','IL','Ic'});
title(app.UIAxes3_2,'Ionic Currrents and Capacitive Current vs Time');
xlabel(app.UIAxes3_2,'Time (usec)');
ylabel(app.UIAxes3_2,'Current(uA/cm^2)');
hold(app.UIAxes3_2,'off');
hold(app.UIAxes3_3,'on');
plot(app.UIAxes3_3,t,gK(1:length(t)));
plot(app.UIAxes3_3,t,gNa(1:length(t)));
plot(app.UIAxes3_3,t,gL(1:length(t)));
legend(app.UIAxes3_3,{'gK','gNa','gL'});
title(app.UIAxes3_3,'Channel Conductances vs Time');
xlabel(app.UIAxes3_3,'Time (usec)');
ylabel(app.UIAxes3_3,'Conductance(mS/cm^2)');
hold(app.UIAxes3_3,'off')
plot(app.UIAxes3_4,t,Vm(1:length(t)));
title(app.UIAxes3_4,'Membrane Voltage vs Time');
xlabel(app.UIAxes3_4,'Time (usec)');
ylabel(app.UIAxes3_4,'Voltage(mV)')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @DurationEditFieldValueChanged, true);
            app.NumberEditFieldLabel = uilabel(app.UIFigure);
            app.NumberEditFieldLabel.Text = 'Number';
            app.NumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberEditField.ValueChangedFcn = createCallbackFcn(app, @NumberEditFieldValueChanged, true);
            app.DelayEditFieldLabel = uilabel(app.UIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelayEditField.ValueChangedFcn = createCallbackFcn(app, @DelayEditFieldValueChanged, true);
            app.ActionButton = uibutton(app.UIFigure, 'push');
            app.ActionButton.ButtonPushedFcn = createCallbackFcn(app, @ActionButtonPushed, true);
            app.ActionButton.Text = 'Action';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            title(app.UIAxes3_3, 'Title')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            app.UIAxes3_4 = uiaxes(app.UIFigure);
            title(app.UIAxes3_4, 'Title')
            xlabel(app.UIAxes3_4, 'X')
            ylabel(app.UIAxes3_4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EE416TermProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end